classdef BurnInBoardMaintenanceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BurnInBoardMaintenanceUIFigure  matlab.ui.Figure
        LabelStatus                     matlab.ui.control.Label
        ConnectPanel                    matlab.ui.container.Panel
        CommunicationSettings           matlab.ui.control.Button
        TestButton                      matlab.ui.control.Button
        DMM6500IDLabel                  matlab.ui.control.Label
        MBCEditField                    matlab.ui.control.EditField
        UUIDLabel_2                     matlab.ui.control.Label
        UUID_DCDC                       matlab.ui.control.EditField
        UUIDLabel                       matlab.ui.control.Label
        DCDCSWVersion                   matlab.ui.control.EditField
        UUIDLabel_3                     matlab.ui.control.Label
        UUID_Main                       matlab.ui.control.EditField
        DMM6500IDEditFieldLabel         matlab.ui.control.Label
        DMM6500IDEditField              matlab.ui.control.EditField
        I2COffsetDropDownLabel          matlab.ui.control.Label
        I2COffsetDropDown               matlab.ui.control.DropDown
        ConnectDMMButton                matlab.ui.control.Button
        ConnectMBCButton                matlab.ui.control.Button
        ConnectBoardsButton             matlab.ui.control.Button
        DMMStatus                       matlab.ui.control.Lamp
        MBCStatus                       matlab.ui.control.Lamp
        DCDCStatus                      matlab.ui.control.Lamp
        DCDCLabel                       matlab.ui.control.Label
        MainBoardLabel                  matlab.ui.control.Label
        MainBoardStatus                 matlab.ui.control.Lamp
        GetUUID_DCDC                    matlab.ui.control.Button
        SetUUID_DCDC                    matlab.ui.control.Button
        GetUUID_Main                    matlab.ui.control.Button
        SetUUID_Main                    matlab.ui.control.Button
        MainBoardPanel                  matlab.ui.container.Panel
        SkipTimer                       matlab.ui.control.Button
        InteractiveMeasurement          matlab.ui.control.CheckBox
        SkipEvery2ndCheckBox            matlab.ui.control.CheckBox
        SetFolder                       matlab.ui.control.Button
        Folder                          matlab.ui.control.EditField
        FolderEditFieldLabel            matlab.ui.control.Label
        Postfix                         matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
        TimerMeasurementStatus          matlab.ui.control.Label
        MeasureButton                   matlab.ui.control.StateButton
        MeasureEvery                    matlab.ui.control.NumericEditField
        everyEditFieldLabel             matlab.ui.control.Label
        minLabel_2                      matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        T2Label_2                       matlab.ui.control.Label
        T3EditField                     matlab.ui.control.NumericEditField
        T2Label                         matlab.ui.control.Label
        T2EditField                     matlab.ui.control.NumericEditField
        T1EditField                     matlab.ui.control.NumericEditField
        T1Label                         matlab.ui.control.Label
        GetTemperaturesButton           matlab.ui.control.Button
        ADCREditFieldLabel              matlab.ui.control.Label
        ADCREditField                   matlab.ui.control.NumericEditField
        ADCVEditFieldLabel              matlab.ui.control.Label
        ADCVEditField                   matlab.ui.control.NumericEditField
        DMMREditFieldLabel              matlab.ui.control.Label
        DMMREditField                   matlab.ui.control.NumericEditField
        DMMVEditFieldLabel              matlab.ui.control.Label
        DMMVEditField                   matlab.ui.control.NumericEditField
        MuxDropDownLabel                matlab.ui.control.Label
        MuxDropDown                     matlab.ui.control.DropDown
        StatusLEDCheckBox               matlab.ui.control.CheckBox
        SingleMeasurementButton         matlab.ui.control.Button
        VLabel                          matlab.ui.control.Label
        OhmLabel                        matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        OhmLabel_2                      matlab.ui.control.Label
        MeasurementButton               matlab.ui.control.Button
        DCDCConverterPanel              matlab.ui.container.Panel
        DutyCycle2SpinnerLabel          matlab.ui.control.Label
        DutyCycle2Spinner               matlab.ui.control.Spinner
        Frequency2SpinnerLabel          matlab.ui.control.Label
        Frequency2Spinner               matlab.ui.control.Spinner
        GetStatus                       matlab.ui.control.Button
        ATtinyStatusEditField           matlab.ui.control.EditField
        ATtinyStatusEditField_2Label    matlab.ui.control.Label
        GetATtinyStatus                 matlab.ui.control.Button
        SetATtinyStatus                 matlab.ui.control.Button
        LightShowButton                 matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        BlueEditField                   matlab.ui.control.NumericEditField
        BlueEditFieldLabel              matlab.ui.control.Label
        RedEditField                    matlab.ui.control.NumericEditField
        RedEditFieldLabel               matlab.ui.control.Label
        ApplyimmediatelyCheckBox        matlab.ui.control.CheckBox
        SwitchOffOthersCheckBox         matlab.ui.control.CheckBox
        GreenEditField                  matlab.ui.control.NumericEditField
        GreenEditFieldLabel             matlab.ui.control.Label
        RGBLEDSpinner                   matlab.ui.control.Spinner
        LEDLabel                        matlab.ui.control.Label
        SwitchOffTimerEditField         matlab.ui.control.NumericEditField
        SwitchOffTimerEditFieldLabel    matlab.ui.control.Label
        minLabel                        matlab.ui.control.Label
        SetTimer                        matlab.ui.control.Button
        GetTimer                        matlab.ui.control.Button
        SwitchDCDCConStatusLED          matlab.ui.control.DiscreteKnob
        StatusLEDLabel                  matlab.ui.control.Label
        DutyCycleLabel                  matlab.ui.control.Label
        DutyCycle1Spinner               matlab.ui.control.Spinner
        FrequencyLabel                  matlab.ui.control.Label
        Frequency1Spinner               matlab.ui.control.Spinner
        VDC2Label                       matlab.ui.control.Label
        VDC2Spinner                     matlab.ui.control.Spinner
        VDC1Label                       matlab.ui.control.Label
        VDC1Spinner                     matlab.ui.control.Spinner
        UITable                         matlab.ui.control.Table
        CLR_FAULTSButton                matlab.ui.control.Button
        STOREButton                     matlab.ui.control.Button
        CLR_FAULTSButton_2              matlab.ui.control.Button
        STOREButton_2                   matlab.ui.control.Button
        SwitchVDC1                      matlab.ui.control.DiscreteKnob
        SwitchVDC2                      matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = public)
        burnInDevice BurnInDev;

        mbc MBC_TB;
        dmm Keithley6500;
    end
    properties (Access = private)
        appName = "BurnIn Board Maintenance App";
        version = "3.2.2";
        settings BurnInSettings;
        burnInMaintenanceMeasApp
        CML=["n/a"; "n/a"];
        WORD=["n/a"; "n/a"];
        VOUT=["n/a"; "n/a"];
        INPUT=["n/a"; "n/a"];
        VDC=["n/a"; "n/a"];
        IOUT=["n/a"; "n/a"];
        VIN=["n/a"; "n/a"];
        IIN=["n/a"; "n/a"];
        TEMP = ["n/a"; "n/a"];
        MBCStatusString='';
        DMMStatusString='';
        DCDCStatusString='';
        MainBoardStatusString='';

        MeasurementTimer;
        MeasurementTimerCountDown=0;
        MeasurementIsRunning = false;
    end
   
  
    methods (Access = private)
        
        function showStatus(app, VDC)
            if VDC <= app.burnInDevice.DCDCConv.nChannels
                app.CML(VDC,1) = sprintf('0x%X', app.burnInDevice.DCDCConv.SiC450(VDC).getSTATUS_CML());
                app.WORD(VDC,1) = sprintf('0x%X', app.burnInDevice.DCDCConv.SiC450(VDC).getSTATUS_WORD());
                app.VOUT(VDC,1) = sprintf('0x%X', app.burnInDevice.DCDCConv.SiC450(VDC).getSTATUS_VOUT());
                app.INPUT(VDC,1) = sprintf('0x%X', app.burnInDevice.DCDCConv.SiC450(VDC).getSTATUS_INPUT());
                app.VDC(VDC,1) = sprintf('%.3f', app.burnInDevice.DCDCConv.SiC450(VDC).getREAD_VOUT());
                app.IOUT(VDC,1) = sprintf('%.3f', app.burnInDevice.DCDCConv.SiC450(VDC).getREAD_IOUT());
                app.VIN(VDC,1) = sprintf('%.3f', app.burnInDevice.DCDCConv.SiC450(VDC).getREAD_VIN());
                app.IIN(VDC,1) = sprintf('%.3f', app.burnInDevice.DCDCConv.SiC450(VDC).getREAD_IIN());
                app.TEMP(VDC,1) = sprintf('%.3f', app.burnInDevice.DCDCConv.SiC450(VDC).getREAD_TEMPERATURE());
            else
                app.CML(VDC,1) = 'n/a';
                app.WORD(VDC,1) = 'n/a';
                app.VOUT(VDC,1) = 'n/a';
                app.INPUT(VDC,1) = 'n/a';
                app.VDC(VDC,1) = 'n/a';
                app.IOUT(VDC,1) = 'n/a';
                app.VIN(VDC,1) = 'n/a';
                app.IIN(VDC,1) = 'n/a';
                app.TEMP(VDC,1) = 'n/a';
            end
             app.UITable.Data = table(app.CML, app.WORD, app.VOUT, app.INPUT, app.VDC, app.IOUT, app.VIN, app.IIN, app.TEMP);
        end
        
        function ret = connectMBC(app, str)
            ret = 1;
            try
                app.MBCStatusString='';
                app.MBCEditField.Value = "Disconnecting...";
                app.UpdateStatus();
                drawnow();
                %Disconnect MBC if connected
                try
                    if ~isempty(app.mbc)
                    %Switch off Multiplexer if possible
                    try
                        app.burnInDevice.mainBoard.setMUX(-1);
                    catch
                    end
                        delete(app.mbc);
                    end
                catch
                end
                app.settings.I2CAddrOffset = str2num(app.I2COffsetDropDown.Value);
                app.MBCEditField.Value = sprintf("Connecting to %s ...", str);
                drawnow();
                app.mbc = MBC_TB(str);
                if(app.mbc.mode == MBCModeEnum.TCP)
                    %in case of TCP, offset is always 0
                    app.settings.I2CAddrOffset = 0;
                    app.I2COffsetDropDown.Value = int2str(app.settings.I2CAddrOffset);
                end
                app.mbc.connectToTCPServer(app.settings.I2CAddrOffset, str, 10);
                app.mbc.reset();
                %TODO
                %app.MAC.Value = app.mbc.getMACaddressString();
            catch exc1
                app.MBCEditField.Value = sprintf("Connecting to %s failed", str);
                if contains(exc1.message, "For more information")
                    msg = extractBefore(exc1.message, "For more information");
                else
                    msg = exc1.message;
                end
                app.MBCStatusString = sprintf("Could not connect to MBC on %s: %s.\n\n", str, msg);
                fprintf("%s\n", app.MBCStatusString);
                try
                    if ~isempty(app.mbc)
                        delete(app.mbc);
                    end
                catch exc2
                    fprintf("Something went wrong (%s).\n", exc2.message);
                end
                ret = 0;
            end
            app.MBCEditField.Value = str;
            drawnow();
            %Connect Boards
            if isvalid(app.mbc)
                app.updateVisibilty();
                app.connectBoards(app.settings.I2CAddrOffset);
            else
                app.burnInDevice.DCDCConv.status = 0;
                app.burnInDevice.mainBoard.status = 0;
                app.UUID_Main.Value = '';
                app.UUID_DCDC.Value = '';
                app.DCDCSWVersion.Value = '';
                app.ATtinyStatusEditField.Value = '';
            end
            app.updateVisibilty();
        end

        function ret = connectBoards(app, offset)
            ret = 1;
            app.UUID_DCDC.Value = 'Connecting...';
            app.DCDCSWVersion.Value = '';
            app.DCDCStatusString = '';
            app.ATtinyStatusEditField.Value = 'Unknown';
            app.MainBoardStatusString = '';
            app.UpdateStatus();
            drawnow();
            
            % try
            %     app.mbc.connectToTCPServer(offset, 10);
            % catch exc1
            %     app.MBCStatusString = sprintf("Could not connect to MBC on %s: %s.\n\n", app.mbc.port, extractBefore(exc1.message, "For more information"));
            %     fprintf("%s\n", app.MBCStatusString);
            %     try
            %         if ~isempty(app.mbc)
            %             delete(app.mbc);
            %         end
            %     catch exc2
            %         fprintf("Something went wrong (%s).\n", exc2.message);
            %     end
            %     ret = 0;
            % end
            bOK = false;
            if isvalid(app.mbc)
                if (app.burnInDevice.connectDCDCConverterBoard(app.mbc, offset) > 0) && (app.burnInDevice.DCDCConv.status == 1)
                    app.DCDCSWVersion.Value = app.burnInDevice.DCDCConv.getSWVersionString();
                    app.UUID_DCDC.Value = app.burnInDevice.DCDCConv.getUUIDString();
                    app.ATtinyStatusEditField.Value = sprintf('%d', app.burnInDevice.DCDCConv.getATtinyStatus());
                    app.UUID_Main.Value = 'Connecting...';
                    drawnow();
                    bOK = true;
                    if (app.burnInDevice.connectMainBoard() == 1) && (app.burnInDevice.mainBoard.status == 1)
                        app.UUID_Main.Value = app.burnInDevice.mainBoard.EEPROM.getUUIDString();
                    else
                        app.UUID_Main.Value = '';
                        app.MainBoardStatusString = sprintf("Problem during connecting to Main Board with address offset %d: %s\n", offset, app.burnInDevice.mainBoard.lastErrorMsg);
                    end
                end
            else
                app.burnInDevice.DCDCConv.status = 0;
                app.burnInDevice.mainBoard.status = 0;
            end
            if ~bOK
                app.UUID_Main.Value = '';
                app.UUID_DCDC.Value = '';
                app.burnInDevice.mainBoard.status = 0;
                app.DCDCStatusString = sprintf("Problem during connecting to DC/DC Converter Board with address offset %d: %s\n", offset, app.burnInDevice.DCDCConv.lastErrorMsg);
            end
            %Fill MuxDropDown entries
            chr(1) = "";
            for k=1:60*app.burnInDevice.DCDCConv.nChannels
                chr(k)=int2str(k);
            end
            if app.burnInDevice.DCDCConv.nChannels > 1
                app.MuxDropDown.Items = ["Disable", chr, "VDC1", "VDC2"];
            else
                app.MuxDropDown.Items = ["Disable", chr, "VDC1"];
            end

            drawnow();
        end
        
        function ret = connectDMM(app, str)
            ret = 1;
            try
                app.dmm = Keithley6500(str);
                app.dmm.init();
                app.dmm.set_VDC();
                app.DMMStatusString = '';
            catch exception1
                fprintf("Could not connect to Keithley DMM6500 (%s).\n", exception1.message);
                app.DMMStatusString = sprintf("Could not connect to Keithley DMM6500 (%s).\n\n", exception1.message);
                try
                    if ~isempty(app.dmm)
                        delete(app.dmm);
                    end
                catch exception2
                    fprintf("Something went wrong (%s).\n", exception2.message);
                end
                ret = 0;
            end
        end
        
        function updateVisibilty(app)
        
            if isvalid(app.dmm)
                app.DMMStatus.Color = [0,1,0];
                app.SingleMeasurementButton.Enable = true;
                app.MeasurementButton.Enable = true;
            else
                app.DMMStatus.Color = [1,0,0];
                app.SingleMeasurementButton.Enable = false;
                app.MeasurementButton.Enable = false;
            end
           
            if isvalid(app.mbc)
                app.MBCStatus.Color = [0,1,0];
                app.ConnectBoardsButton.Enable = true;
                if app.mbc.mode == MBCModeEnum.TCP
                    strEnable = "on";
                    app.I2COffsetDropDown.Enable = "off";
                else
                    strEnable = "off";
                    app.I2COffsetDropDown.Enable = "on";
                end
            else
                app.MBCStatus.Color = [1,0,0];
                app.ConnectBoardsButton.Enable = false;
                app.SingleMeasurementButton.Enable = false;
                app.MeasurementButton.Enable = false;
                strEnable = "off";
            end
            app.CommunicationSettings.Enable = strEnable;

            if app.burnInDevice.DCDCConv.status
                app.DCDCStatus.Color = [0,1,0];
                app.DCDCConverterPanel.Enable = 'on';
                app.GetUUID_DCDC.Enable = true;
                app.SetUUID_DCDC.Enable = true;
                enableChannel2Controls = 'on';
                if app.burnInDevice.DCDCConv.nChannels == 1
                    enableChannel2Controls = 'off';
                end
                app.Frequency2Spinner.Enable = enableChannel2Controls;
                app.DutyCycle2Spinner.Enable = enableChannel2Controls;
                app.SwitchVDC2.Enable = enableChannel2Controls;
                app.VDC2Spinner.Enable = enableChannel2Controls;
                app.CLR_FAULTSButton_2.Enable = enableChannel2Controls;
                app.STOREButton_2.Enable = enableChannel2Controls;
            else
                app.DCDCStatus.Color = [1,0,0];
                app.DCDCConverterPanel.Enable = 'off';
                app.GetUUID_DCDC.Enable = false;
                app.SetUUID_DCDC.Enable = false;
            end
            if app.burnInDevice.mainBoard.status
                app.MainBoardStatus.Color = [0,1,0];
                app.MainBoardPanel.Enable = 'on';
                app.SingleMeasurementButton.Enable = true;
                app.MeasurementButton.Enable = true;
                app.GetUUID_Main.Enable = true;
                app.SetUUID_Main.Enable = true;
            else
                app.MainBoardStatus.Color = [1,0,0];
                app.MainBoardPanel.Enable = 'off';
                app.GetUUID_Main.Enable = false;
                app.SetUUID_Main.Enable = false;
            end
            app.UpdateStatus();
        end
        
        function ret = UpdateStatus(app)
            ret=0;
            app.LabelStatus.Text = sprintf('%s%s%s%s', app.MBCStatusString, app.DMMStatusString, app.DCDCStatusString, app.MainBoardStatusString);
        end
        function ret = checkUUID(app, str)
            ret = 1;
            uuid = zeros(1,16, 'uint8');
            str = regexprep(str,'[-]','');
            if strlength(str) == 32
                try
                    for i=1:1:16
                        uuid(i) = hex2dec(str((2*i - 1):(2*i)));
                    end
                catch e
                    strError = sprintf('Error in UUID: %s is not a valid hex number!', str((2*i - 1):(2*i)));
                    msgbox(strError,"Error","error", "modal");
                    ret = 0;
                end
            else
                msgbox("Error in UUID: The entered UUID is too short, please enter 16 hex values!","Error","error", "modal");
                ret = 0;
            end
        end
        
        function ret = MeasurementTimerFunc(app,~,~)
            ret = 1;
            %fprintf('count down = %d\n', app.MeasurementTimerCountDown);
            app.MeasurementTimerCountDown=app.MeasurementTimerCountDown-1;
            if app.MeasurementIsRunning == false
                if app.MeasurementTimerCountDown <= 0
                    try
                        app.MeasurementIsRunning = true;
                        app.TimerMeasurementStatus.Text = 'Measurement is running...';
                        app.TimerMeasurementStatus.FontColor = [1 0 0];
                        app.DCDCConverterPanel.Enable = 'off';
                        app.MainBoardPanel.Enable = 'off';
                        app.ConnectPanel.Enable = 'off';
                        drawnow();
                        sound(sin(2:30));
                        %TODO: Disable everything
                        dDate = datetime('now', 'Format', 'yyyy-MM-dd');
                        dTime = datetime('now', 'Format', 'HH:mm:ss');    
                        if app.SkipEvery2ndCheckBox.Value
                            nSkip = 2;
                        else
                            nSkip = 0;
                        end
                        %Do Measurement
                        if app.InteractiveMeasurement.Value
                            app.burnInMaintenanceMeasApp = BurnInBoardMaintenanceMeasApp(app);
    
                            [VDC, VR, R, ~] = app.burnInDevice.completeMeasurement([nSkip, nSkip], 5, 120, app.dmm, app.burnInMaintenanceMeasApp);
                            delete(app.burnInMaintenanceMeasApp);
                        else
                            [VDC, VR, R, ~] = app.burnInDevice.completeMeasurement([nSkip, nSkip], 5, 120, app.dmm, BurnInBoardMaintenanceMeasApp.empty);
                        end
        
                        app.burnInDevice.saveMeasurement(app.Folder.Value, dDate, dTime, app.Postfix.Value, "", VDC, VR, R);
        
                    catch exception
                        fprintf('Something went wrong during measurement or saving\n');
                    end
                    app.MeasurementTimerCountDown = app.MeasureEvery.Value * 6;
                    
                    app.DCDCConverterPanel.Enable = 'on';
                    app.MainBoardPanel.Enable = 'on';
                    app.ConnectPanel.Enable = 'on';
                    app.TimerMeasurementStatus.FontColor = [0 0 0];
                    app.MeasurementIsRunning = false;
                end
            end
            %fprintf('count down = %d\n', app.MeasurementTimerCountDown);
            app.TimerMeasurementStatus.Text = sprintf('Next Measurement in %0.1f min', app.MeasurementTimerCountDown / 6);
        end

        function ret = lightShowRGBLED(app)
            ret = 0;
            progress = uiprogressdlg( app.BurnInBoardMaintenanceUIFigure, 'Title', 'RGB LED Light Show:', 'Value', 0, "Cancelable","on","CancelText",'Cancel');
            for k=1:1:123
                progress.Value = k/123;
                progress.Message = sprintf('%d /123', k);
                app.burnInDevice.mainBoard.switchRGBLED(k, app.RedEditField.Value, app.GreenEditField.Value, app.BlueEditField.Value, 1, 1);
                if progress.CancelRequested
                    break;
                end
                pause(0.5);
            end
            app.burnInDevice.mainBoard.switchRGBLED(123, 0, 0, 0, 1, 1);
            ret = 1;
        end

        function longTermTest(app, host)
            fprintf('Started at: %s\n', datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'));

            t = uint16(60);
        
            %mbc = MBC_TB(host);
            index = 0;
            % try
            %     mbc.connectToTCPServer(index, host, 10);
            %     % when I do this on F103RB with Ethernet3 driver, readline delivers timeouts! Doing it later works fine!
            %     pause(0.5);
            %     %mbc.reset();
            % catch exc1
            %     MBCStatusString = sprintf("Could not connect to MBC on %s: %s.\n\n", mbc.port, extractBefore(exc1.message, "For more information"));
            %     fprintf("%s\n", MBCStatusString);
            %     try
            %         if ~isempty(mbc)
            %             delete(mbc);
            %         end
            %     catch exc2
            %         fprintf("Something went wrong (%s).\n", exc2.message);
            %     end
            %     return;
            % end
            % fprintf('MBC connected to %s: \n', host);
            
            fprintf('Resetting MBC\n');
            app.mbc.reset();
        
            %ScanI2CAddr(mbc);
            %scanI2CAddrWithMultiplexer(mbc, index);
            
            app.readTemperatures(app.mbc, index, true);
        
            %Initialize
            iRun = 0;
            iLED = 1;
            app.setTimer(app.mbc, index, 0);
            app.switchVDC(app.mbc, index, 1, 0);
            progress = 0;
            progressWnd = uiprogressdlg(app.BurnInBoardMaintenanceUIFigure, 'Title', 'Long Term Test', 'Value', 0, "Cancelable","on","CancelText",'Cancel');
            tic;
            while app.mbc.tcpClient(index+1).status.getStatus() == TCPClientStatusEnum.CONNECTED
                iRun = iRun + 1;
                if toc > 1
                    if progress>10
                        fprintf('\b');
                    else
                        fprintf('.');
                    end
                    if progress == 20
                        progress = 0;
                    end
                    progress = progress + 1;
                    progressWnd.Value = progress / 20;
                    progressWnd.Message = sprintf('Run: %.0f', iRun);
                    if progressWnd.CancelRequested
                        return;
                    end
                    tic;
                end
        
                try
                    if app.getVDCswitchStatus(app.mbc, index, 1) == 0
                        fprintf('\nTimer elapsed and restarted at: %s\n', datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'));
                        
                        %If I call this here, an exception is thrown
                        %ScanI2CAddr(mbc);
                        
                        app.switchVDC(app.mbc, index, 1, 2);
                        app.setTimer(app.mbc, index, t);
                        progress = 1;
                    end
                    T = app.readTemperatures(app.mbc, index, 0);
                    app.T1EditField.Value = T(1);
                    app.T2EditField.Value = T(2);
                    app.T3EditField.Value = T(3);
                    app.setRGBLED(app.mbc, index, iLED, 255, 0, 0);
                    if iLED < 123
                        iLED = iLED + 1;
                    else
                        iLED = 1;
                    end
                catch exc1
                    if app.mbc.tryReconnect() == 0
                        fprintf("Reconnect failed.\n");
                        break;
                    else
                        fprintf("Reconnected.\n");
                    end
                end
            end
            fprintf('Finished at: %s\n', datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'));
        end

        function ret = setTimer(app, mbc, index, t)
            ret = mbc.writeBytesI2C(0x08, index, [128 + 4 uint8(mod(uint16(t), 256)) uint8(bitshift(uint16(t), -8, 'uint16'))]);
        end
        
        function ret = switchVDC(app, mbc, index, VDC, v)
            %switch ATtiny: 0-off, 1-on, 2-blink
            ret = mbc.writeBytesI2C(0x08, index, [(127+VDC) uint8(v)]);
        end
        
        function status = getVDCswitchStatus(app, mbc, index, VDC)
            status = mbc.queryBytesI2C(0x08, index, (127+VDC), 1, 1);
        end

        function T = readTemperatures(app, mbc, index, print)
            MPaddr = 0xE0; %Address of I2C Multiplexer (set to 0 if there is no)
            cOn = 0x04;         %Command to switch channel on
            cOff = 0x00;        %Command to switch channel off
            %Switch I2CMultiplexer on
            %I2Cmulti = I2CMultiplexerPCA9542A(mbc, index, MPaddr, cOn, cOff);
            tempSensor(1) = TempSensor_TMP1075(mbc, 0x94, index, MPaddr, cOn, cOff);
            tempSensor(2) = TempSensor_TMP1075(mbc, 0x98, index, MPaddr, cOn, cOff);
            tempSensor(3) = TempSensor_TMP1075(mbc, 0x9C, index, MPaddr, cOn, cOff);
        
            for i=1:1:3
                T(i) = tempSensor(i).getTemperatureCelsius();
                if print
                    fprintf("%f °C\n", T(i));
                end
            end
        end
        
        function setRGBLED(app, mbc, index, iLED, R, G, B)
            brightnessPosLEDs = 100;
            switchOffLEDs = 1;
            setLEDs = 1;
            mbc.writeBytesI2C(0x08, index, [uint8(iLED-1) uint8(R * brightnessPosLEDs / 100) uint8(G * brightnessPosLEDs / 100) uint8(B * brightnessPosLEDs / 100) uint8(switchOffLEDs) uint8(setLEDs)]);
        end

    end
    methods (Access = public)
        function ret = getSwitchVDC(app, i)
            ret = 0;
            switch i
                case 1
                    ret = app.SwitchVDC1.Value;
                case 2
                    ret = app.SwitchVDC2.Value;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app)
            movegui(app.BurnInBoardMaintenanceUIFigure,"center");
            app.BurnInBoardMaintenanceUIFigure.Name = convertStringsToChars(sprintf("%s (Version: %s)", app.appName, app.version));

            app.settings = BurnInSettings("BurnInMaintenanceSettings");

            app.burnInDevice = BurnInDev();

            app.MBCEditField.Value = app.settings.MBC_Port;
            app.DMM6500IDEditField.Value = app.settings.DMM6500_ID;
            app.I2COffsetDropDown.Value = int2str(app.settings.I2CAddrOffset);
            app.Folder.Value = app.settings.folder;
            app.Postfix.Value = app.settings.postfix;
            
            %TODO: Connect at Start disabled
            %app.connectMBC(app.settings.MBC_Port);
                       
            %app.connectDMM(app.settings.DMM6500_ID);
                    
            app.updateVisibilty();

            app.MeasurementTimer = timer('ExecutionMode', 'fixedRate', 'Period', 10, 'BusyMode', 'drop', 'TimerFcn', @app.MeasurementTimerFunc);
            app.SkipTimer.Enable = false;
        end

        % Value changed function: StatusLEDCheckBox
        function statusLEDCheckBoxChanged(app, event)
            value = app.StatusLEDCheckBox.Value;
            app.burnInDevice.mainBoard.switchStatusLED(value);
%             if value
%                 fprintf('Status LED ON\n');
%             else
%                 fprintf('Status LED OFF\n');
%             end
        end

        % Value changed function: VDC1Spinner
        function VDC1SpinnerValueChanged(app, event)
            value = app.VDC1Spinner.Value;
            app.burnInDevice.DCDCConv.setSiC450_Voltage(1, value);
            app.showStatus(1);
        end

        % Button pushed function: CLR_FAULTSButton
        function CLEAR_FAULTS_VDC1_ButtonPushed(app, event)
            app.burnInDevice.DCDCConv.SiC450(1).setCLEAR_FAULTS();
            app.showStatus(1);
        end

        % Close request function: BurnInBoardMaintenanceUIFigure
        function BurnInBoardMaintenanceUIFigureCloseRequest(app, event)
            try
                stop(app.MeasurementTimer);
                delete(app.MeasurementTimer);
                app.settings.DMM6500_ID = app.DMM6500IDEditField.Value;
                app.settings.MBC_Port = app.MBCEditField.Value;
                app.settings.I2CAddrOffset = str2num(app.I2COffsetDropDown.Value);
                app.settings.folder = app.Folder.Value;
                app.settings.postfix = app.Postfix.Value;
                delete(app.settings);

                app.burnInDevice.switchOff(true);
                delete(app.burnInDevice);

                delete(app.board);
                delete(app.mainBoard);
                app.dmm.set_cnt();
                delete(app.dmm);
            catch exception
            end
            delete(app.burnInMaintenanceMeasApp)
            delete(app);
        end

        % Button pushed function: STOREButton
        function STOREButtonPushedVDC1(app, event)
            app.burnInDevice.DCDCConv.setSiC450_StoreDefaults(1);
        end

        % Value changed function: VDC2Spinner
        function VDC2SpinnerValueChanged(app, event)
            value = app.VDC2Spinner.Value;
            app.burnInDevice.DCDCConv.setSiC450_Voltage(2, value);
            app.showStatus(2);
        end

        % Button pushed function: CLR_FAULTSButton_2
        function CLEAR_FAULTS_VDC2_ButtonPushed(app, event)
            app.burnInDevice.DCDCConv.SiC450(2).setCLEAR_FAULTS();
            app.showStatus(2);
        end

        % Button pushed function: STOREButton_2
        function STORE_USER_ALLButtonPushedVDC2(app, event)
            app.burnInDevice.DCDCConv.setSiC450_StoreDefaults(2);
        end

        % Value changed function: MuxDropDown
        function MuxChannelChanged(app, event)
            value = app.MuxDropDown.Value;
            if value == "Disable"
                app.burnInDevice.mainBoard.setMUX(-1);
            elseif value == "VDC1"
                app.burnInDevice.mainBoard.setMUX(app.burnInDevice.GetVDCChannel(1));
            elseif value == "VDC2"
                app.burnInDevice.mainBoard.setMUX(app.burnInDevice.GetVDCChannel(2));
            else
                v = str2num(value) - 1;
                %fprintf("Mux = %d", v);
                app.burnInDevice.mainBoard.setMUX(v);
            end
        end

        % Button pushed function: SingleMeasurementButton
        function SingleMeasurementButtonPressed(app, event)
            app.DMMREditField.Value = inf;
            app.ADCREditField.Value = inf;
            app.DMMVEditField.Value = inf;
            app.ADCVEditField.Value = inf;
            R_ADC = inf;
            V_ADC = inf;
            R_DMM = inf;
            V_DMM = inf;
            value = app.MuxDropDown.Value;
            channel = -1;
            if value == "Disable"
                app.burnInDevice.mainBoard.setMUX(-1);
            elseif value == "VDC1"
                app.burnInDevice.mainBoard.setMUX(app.burnInDevice.GetVDCChannel(1));
                %V_ADC = app.burnInDevice.mainBoard.adc.getVoltage(ADS1115.CONFIG_MUX_SINGLE_0, ADS1115.GAIN_TWOTHIRDS_6_144);
                V_ADC = app.burnInDevice.mainBoard.adc.getVoltageDiff(ADS1115.CONFIG_MUX_DIFF_0_1, ADS1115.GAIN_TWOTHIRDS_6_144);
            elseif value == "VDC2"
                app.burnInDevice.mainBoard.setMUX(app.burnInDevice.GetVDCChannel(2));
                %V_ADC = app.burnInDevice.mainBoard.adc.getVoltage(ADS1115.CONFIG_MUX_SINGLE_0, ADS1115.GAIN_TWOTHIRDS_6_144);
                V_ADC = app.burnInDevice.mainBoard.adc.getVoltageDiff(ADS1115.CONFIG_MUX_DIFF_0_1, ADS1115.GAIN_TWOTHIRDS_6_144);
            else
                channel = str2num(value) - 1;
                %fprintf("Mux = %d", channel);
                app.burnInDevice.mainBoard.setMUX(channel);
            end
            app.DMMVEditField.Value = inf;
            if isvalid(app.dmm)
                try
                    %does the DMM really work?
                    V_DMM = app.dmm.meas_VDC();
                catch e
                end
            end
            if (channel >= 0) && (channel < 120)
                if isvalid(app.dmm)
                    [R_DMM, V_DMM, ~] = app.burnInDevice.meas_R(channel,5, app.dmm);
                end
                [R_ADC, V_ADC, ~] = app.burnInDevice.meas_R(channel, 5, Keithley6500.empty);
            end
            app.DMMREditField.Value = R_DMM;
            app.DMMVEditField.Value = V_DMM;
            app.ADCREditField.Value = R_ADC;
            app.ADCVEditField.Value = V_ADC;
            %app.burnInDevice.mainBoard.setMUX(-1);
        end

        % Button pushed function: ConnectDMMButton
        function ConnectDMMButtonPushed(app, event)
            app.settings.DMM6500_ID = app.DMM6500IDEditField.Value;
            app.connectDMM(app.settings.DMM6500_ID);
            app.updateVisibilty();
        end

        % Button pushed function: ConnectMBCButton
        function ConnectMBCButtonPushed(app, event)
            strAddr = app.MBCEditField.Value;
            %if (ischar(strAddr) || isstring(strAddr)) && (checkIPAddress(strAddr) || strAddr == "" || contains(strAddr, "COM"))
            if (ischar(strAddr) || isstring(strAddr))
                app.connectMBC(app.MBCEditField.Value);
            else
                uialert(app.BurnInBoardMaintenanceUIFigure, sprintf("'%s' is not a valid IP Address or a COM port!\n\nEnter a valid IP addresses, or a valid COM port, or just COM!", strAddr), "Input invalid");
            end
            app.updateVisibilty();
        end

        % Button pushed function: ConnectBoardsButton
        function ConnectBoardsButtonPushed(app, event)
            %disable Multiplexer of currently connected board
            if isvalid(app.mbc)
                if app.burnInDevice.mainBoard.status
                    %use try/ catch here to avoid canceling if the main board is no longer available
                    try
                        app.burnInDevice.mainBoard.setMUX(-1);
                    catch
                    end
                end
            end
            app.settings.I2CAddrOffset = str2num(app.I2COffsetDropDown.Value);
            if isvalid(app.mbc)
                app.connectBoards(app.settings.I2CAddrOffset);
            end
            app.updateVisibilty();            
        end

        % Value changed function: SwitchVDC1
        function SwitchVDC1ValueChanged(app, event)
            value = app.SwitchVDC1.Value;
            if value > 0
                v = app.VDC1Spinner.Value;
                app.burnInDevice.DCDCConv.setSiC450_Voltage(1, v);                
            end
            f = app.Frequency1Spinner.Value;
            duty = app.DutyCycle1Spinner.Value;
            app.burnInDevice.DCDCConv.setFrequDutyBank(1, f, duty);
            
            app.burnInDevice.DCDCConv.switchVDC(1, value);
            app.showStatus(1);
        end

        % Value changed function: SwitchVDC2
        function SwitchVDC2ValueChanged(app, event)
            value = app.SwitchVDC2.Value;
            if value > 0
                v = app.VDC2Spinner.Value;
                app.burnInDevice.DCDCConv.setSiC450_Voltage(2, v);                
            end
            f = app.Frequency2Spinner.Value;
            duty = app.DutyCycle2Spinner.Value;
            app.burnInDevice.DCDCConv.setFrequDutyBank(2, f, duty);

            app.burnInDevice.DCDCConv.switchVDC(2, value);
            app.showStatus(2);            
        end

        % Value changed function: Frequency1Spinner
        function Frequency1SpinnerValueChanged(app, event)
            f = app.Frequency1Spinner.Value;
            duty = app.DutyCycle1Spinner.Value;
            app.burnInDevice.DCDCConv.setFrequDutyBank(1, f, duty);
        end

        % Value changed function: DutyCycle1Spinner
        function DutyCycle1SpinnerValueChanged(app, event)
            f = app.Frequency1Spinner.Value;
            duty = app.DutyCycle1Spinner.Value;
            app.burnInDevice.DCDCConv.setFrequDutyBank(1, f, duty);
        end

        % Value changed function: SwitchDCDCConStatusLED
        function SwitchDCDCConStatusLEDValueChanged(app, event)
            value = app.SwitchDCDCConStatusLED.Value;
            app.burnInDevice.DCDCConv.switchStatusLED_DCDC(value);
        end

        % Button pushed function: MeasurementButton
        function MeasurementButtonPushed(app, event)
            app.burnInMaintenanceMeasApp = BurnInBoardMaintenanceMeasApp(app);
        end

        % Button pushed function: GetUUID_Main
        function GetUUID_MainButtonPushed(app, event)
            app.UUID_Main.Value = 'Reading...';
            drawnow();
            app.UUID_Main.Value = app.burnInDevice.mainBoard.EEPROM.getUUIDString();
            drawnow();
            sound(sin(0.5:100));
        end

        % Button pushed function: SetUUID_Main
        function SetUUID_MainButtonPushed(app, event)
            if app.checkUUID(app.UUID_Main.Value)
                if app.burnInDevice.mainBoard.EEPROM.setUUIDString(app.UUID_Main.Value) ~= 1
                    sound(sin(2:30));
                    msgbox("Error writing UUID: Please check the write access jumper!","Error","error", "modal");
                else
                    sound(sin(0.5:100));
                end
            else
                sound(sin(2:30));
            end
        end

        % Button pushed function: GetTemperaturesButton
        function GetTemperaturesButtonPushed(app, event)
            app.T1EditField.Value = app.burnInDevice.mainBoard.tempSensor(1).getTemperatureCelsius();
            app.T2EditField.Value = app.burnInDevice.mainBoard.tempSensor(2).getTemperatureCelsius();
            app.T3EditField.Value = app.burnInDevice.mainBoard.tempSensor(3).getTemperatureCelsius();
        end

        % Button pushed function: GetUUID_DCDC
        function GetUUID_DCDCButtonPushed(app, event)
            app.UUID_DCDC.Value = 'Reading...';
            drawnow();
            app.UUID_DCDC.Value = app.burnInDevice.DCDCConv.getUUIDString();
            app.DCDCSWVersion.Value = app.burnInDevice.DCDCConv.getSWVersionString();
            drawnow();
            sound(sin(0.5:100));
        end

        % Button pushed function: SetUUID_DCDC
        function SetUUID_DCDCButtonPushed(app, event)
            if app.checkUUID(app.UUID_DCDC.Value)
                if app.burnInDevice.DCDCConv.setUUIDString(app.UUID_DCDC.Value) ~= 1
                    sound(sin(2:30));
                    msgbox("Error writing UUID of DCDC Board!","Error","error", "modal");
                else
                    sound(sin(0.5:100));
                end
            else
                sound(sin(2:30));
            end            
        end

        % Button pushed function: SetTimer
        function SetTimerButtonPushed(app, event)
            app.burnInDevice.DCDCConv.setTimer(app.SwitchOffTimerEditField.Value);
        end

        % Button pushed function: GetTimer
        function GetTimerButtonPushed(app, event)
            app.SwitchOffTimerEditField.Value = double(app.burnInDevice.DCDCConv.getTimer());
        end

        % Value changed function: MeasureButton
        function MeasureButtonValueChanged(app, event)
            value = app.MeasureButton.Value;
            if value==0
                stop(app.MeasurementTimer);
                app.MeasureEvery.Enable=true;
                app.Folder.Enable=true;
                app.Postfix.Enable=true;
                app.TimerMeasurementStatus.Visible = false;
                app.SkipTimer.Enable = false;
                app.SetFolder.Enable = true;
                app.SkipEvery2ndCheckBox.Visible = true;
            else
                app.MeasureEvery.Enable=false;
                app.Folder.Enable=false;
                app.Postfix.Enable=false;
                %start timer
                app.MeasurementTimerCountDown = app.MeasureEvery.Value * 6 + 1;
                app.TimerMeasurementStatus.Visible = true;
                app.SkipTimer.Enable = true;
                app.TimerMeasurementStatus.Text = sprintf('Next Measurement in %0.1f min', app.MeasureEvery.Value / 6);
                app.SetFolder.Enable = false;
                app.SkipEvery2ndCheckBox.Visible = false;
                start(app.MeasurementTimer);

            end

        end

        % Button pushed function: SetFolder
        function SetFolderButtonPushed(app, event)
            folder = uigetdir(app.Folder.Value);
            if ~(isequal(folder,0))
                app.Folder.Value = folder;
            end
        end

        % Button pushed function: SkipTimer
        function SkipTimerButtonPushed(app, event)
            app.TimerMeasurementStatus.Text = "Next Measurement starts soon";
            app.TimerMeasurementStatus.FontColor = [0.6 0.0 0.0];
            app.MeasurementTimerCountDown = 0;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.burnInDevice.mainBoard.switchRGBLED(app.RGBLEDSpinner.Value, app.RedEditField.Value, app.GreenEditField.Value, app.BlueEditField.Value, app.SwitchOffOthersCheckBox.Value, app.ApplyimmediatelyCheckBox.Value);
        end

        % Button pushed function: LightShowButton
        function LightShowButtonValueChanged(app, event)
            app.lightShowRGBLED();
        end

        % Button pushed function: SetATtinyStatus
        function SetATtinyStatusButtonPushed(app, event)
            status = str2num(['uint8(',app.ATtinyStatusEditField.Value,')']);
            if isempty(status)
                app.ATtinyStatusEditField.Value = 'No valid input!';
            else
                app.ATtinyStatusEditField.Value = 'Setting...';
                drawnow();
                app.burnInDevice.DCDCConv.setATtinyStatus(status);
                app.ATtinyStatusEditField.Value = 'Getting...';
                drawnow();
                app.ATtinyStatusEditField.Value = sprintf('%d', app.burnInDevice.DCDCConv.getATtinyStatus());
            end
        end

        % Button pushed function: GetATtinyStatus
        function GetATtinyStatusButtonPushed(app, event)
            app.ATtinyStatusEditField.Value = 'Getting...';
            drawnow();
            app.ATtinyStatusEditField.Value = sprintf('%d', app.burnInDevice.DCDCConv.getATtinyStatus());
        end

        % Button pushed function: GetStatus
        function GetStatusButtonPushed(app, event)
            app.showStatus(1);
            app.showStatus(2);
        end

        % Value changed function: DutyCycle2Spinner
        function DutyCycle2SpinnerValueChanged(app, event)
            f = app.Frequency2Spinner.Value;
            duty = app.DutyCycle2Spinner.Value;
            app.burnInDevice.DCDCConv.setFrequDutyBank(2, f, duty);
        end

        % Value changed function: Frequency2Spinner
        function Frequency2SpinnerValueChanged(app, event)
            f = app.Frequency2Spinner.Value;
            duty = app.DutyCycle2Spinner.Value;
            app.burnInDevice.DCDCConv.setFrequDutyBank(2, f, duty);
        end

        % Drop down opening function: MuxDropDown
        function MuxDropDownOpening(app, event)
            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            strAddr = app.MBCEditField.Value;
            app.longTermTest(strAddr);
        end

        % Button pushed function: CommunicationSettings
        function CommunicationSettingsButtonPushed(app, event)
            commSettingsDlg = CommunicationSettingsDlg(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BurnInBoardMaintenanceUIFigure = uifigure('Visible', 'off');
            app.BurnInBoardMaintenanceUIFigure.Name = 'BurnIn Board Maintenance';
            app.BurnInBoardMaintenanceUIFigure.CloseRequestFcn = createCallbackFcn(app, @BurnInBoardMaintenanceUIFigureCloseRequest, true);
            app.DCDCConverterPanel = uipanel(app.BurnInBoardMaintenanceUIFigure);
            app.DCDCConverterPanel.Title = 'DC/DC Converter';
            app.SwitchVDC2 = uiknob(app.DCDCConverterPanel, 'discrete');
            app.SwitchVDC2.Items = {'Off', 'On', 'Blink'};
            app.SwitchVDC2.ItemsData = [0 1 2];
            app.SwitchVDC2.ValueChangedFcn = createCallbackFcn(app, @SwitchVDC2ValueChanged, true);
            app.SwitchVDC2.Value = 0;
            app.SwitchVDC1 = uiknob(app.DCDCConverterPanel, 'discrete');
            app.SwitchVDC1.Items = {'Off', 'On', 'Blink'};
            app.SwitchVDC1.ItemsData = [0 1 2];
            app.SwitchVDC1.ValueChangedFcn = createCallbackFcn(app, @SwitchVDC1ValueChanged, true);
            app.SwitchVDC1.Value = 0;
            app.STOREButton_2 = uibutton(app.DCDCConverterPanel, 'push');
            app.STOREButton_2.ButtonPushedFcn = createCallbackFcn(app, @STORE_USER_ALLButtonPushedVDC2, true);
            app.STOREButton_2.Text = 'STORE';
            app.CLR_FAULTSButton_2 = uibutton(app.DCDCConverterPanel, 'push');
            app.CLR_FAULTSButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEAR_FAULTS_VDC2_ButtonPushed, true);
            app.CLR_FAULTSButton_2.Text = 'CLR_FAULTS';
            app.STOREButton = uibutton(app.DCDCConverterPanel, 'push');
            app.STOREButton.ButtonPushedFcn = createCallbackFcn(app, @STOREButtonPushedVDC1, true);
            app.STOREButton.Text = 'STORE';
            app.CLR_FAULTSButton = uibutton(app.DCDCConverterPanel, 'push');
            app.CLR_FAULTSButton.ButtonPushedFcn = createCallbackFcn(app, @CLEAR_FAULTS_VDC1_ButtonPushed, true);
            app.CLR_FAULTSButton.Text = 'CLR_FAULTS';
            app.UITable = uitable(app.DCDCConverterPanel);
            app.UITable.ColumnName = {'CML'; 'WRD'; 'VO'; 'INP'; 'VDC'; 'IOUT'; 'VIN'; 'IIN'; 'Temp'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'Status'};
            app.VDC1Spinner = uispinner(app.DCDCConverterPanel);
            app.VDC1Spinner.Step = 0.1;
            app.VDC1Spinner.Limits = [0.1 5];
            app.VDC1Spinner.ValueDisplayFormat = '%.1f';
            app.VDC1Spinner.ValueChangedFcn = createCallbackFcn(app, @VDC1SpinnerValueChanged, true);
            app.VDC1Spinner.Tooltip = {'Sets VDC1'};
            app.VDC1Spinner.Value = 3;
            app.VDC1Label = uilabel(app.DCDCConverterPanel);
            app.VDC1Label.Text = 'VDC1:';
            app.VDC2Spinner = uispinner(app.DCDCConverterPanel);
            app.VDC2Spinner.Step = 0.1;
            app.VDC2Spinner.Limits = [0.1 5];
            app.VDC2Spinner.ValueDisplayFormat = '%.1f';
            app.VDC2Spinner.ValueChangedFcn = createCallbackFcn(app, @VDC2SpinnerValueChanged, true);
            app.VDC2Spinner.Tooltip = {'Sets VDC2'};
            app.VDC2Spinner.Value = 3;
            app.VDC2Label = uilabel(app.DCDCConverterPanel);
            app.VDC2Label.Text = 'VDC2:';
            app.Frequency1Spinner = uispinner(app.DCDCConverterPanel);
            app.Frequency1Spinner.Step = 0.1;
            app.Frequency1Spinner.Limits = [0.5 5];
            app.Frequency1Spinner.ValueDisplayFormat = '%.1f';
            app.Frequency1Spinner.ValueChangedFcn = createCallbackFcn(app, @Frequency1SpinnerValueChanged, true);
            app.Frequency1Spinner.Tooltip = {'Sets Frequency'};
            app.Frequency1Spinner.Value = 1;
            app.FrequencyLabel = uilabel(app.DCDCConverterPanel);
            app.FrequencyLabel.Text = 'Frequency1:';
            app.DutyCycle1Spinner = uispinner(app.DCDCConverterPanel);
            app.DutyCycle1Spinner.Step = 10;
            app.DutyCycle1Spinner.Limits = [10 90];
            app.DutyCycle1Spinner.ValueDisplayFormat = '%.0f';
            app.DutyCycle1Spinner.ValueChangedFcn = createCallbackFcn(app, @DutyCycle1SpinnerValueChanged, true);
            app.DutyCycle1Spinner.Tooltip = {'Sets Duty Cycle'};
            app.DutyCycle1Spinner.Value = 50;
            app.DutyCycleLabel = uilabel(app.DCDCConverterPanel);
            app.DutyCycleLabel.Text = 'Duty Cycle1:';
            app.StatusLEDLabel = uilabel(app.DCDCConverterPanel);
            app.StatusLEDLabel.Text = 'Status LED:';
            app.SwitchDCDCConStatusLED = uiknob(app.DCDCConverterPanel, 'discrete');
            app.SwitchDCDCConStatusLED.Items = {'Off', 'On', 'Blink'};
            app.SwitchDCDCConStatusLED.ItemsData = [0 1 2];
            app.SwitchDCDCConStatusLED.ValueChangedFcn = createCallbackFcn(app, @SwitchDCDCConStatusLEDValueChanged, true);
            app.SwitchDCDCConStatusLED.Value = 0;
            app.GetTimer = uibutton(app.DCDCConverterPanel, 'push');
            app.GetTimer.ButtonPushedFcn = createCallbackFcn(app, @GetTimerButtonPushed, true);
            app.GetTimer.Text = 'Get';
            app.SetTimer = uibutton(app.DCDCConverterPanel, 'push');
            app.SetTimer.ButtonPushedFcn = createCallbackFcn(app, @SetTimerButtonPushed, true);
            app.SetTimer.Text = 'Set';
            app.minLabel = uilabel(app.DCDCConverterPanel);
            app.minLabel.Text = 'min';
            app.SwitchOffTimerEditFieldLabel = uilabel(app.DCDCConverterPanel);
            app.SwitchOffTimerEditFieldLabel.Text = 'Switch Off Timer:';
            app.SwitchOffTimerEditField = uieditfield(app.DCDCConverterPanel, 'numeric');
            app.SwitchOffTimerEditField.Limits = [0 65536];
            app.SwitchOffTimerEditField.ValueDisplayFormat = '%.0f';
            app.SwitchOffTimerEditField.Value = 10;
            app.LEDLabel = uilabel(app.DCDCConverterPanel);
            app.LEDLabel.Text = 'RGB LED:';
            app.RGBLEDSpinner = uispinner(app.DCDCConverterPanel);
            app.RGBLEDSpinner.Limits = [1 123];
            app.RGBLEDSpinner.Value = 1;
            app.GreenEditFieldLabel = uilabel(app.DCDCConverterPanel);
            app.GreenEditFieldLabel.Text = 'Green:';
            app.GreenEditField = uieditfield(app.DCDCConverterPanel, 'numeric');
            app.GreenEditField.Limits = [0 255];
            app.GreenEditField.ValueDisplayFormat = '%.0f';
            app.GreenEditField.Value = 32;
            app.SwitchOffOthersCheckBox = uicheckbox(app.DCDCConverterPanel);
            app.SwitchOffOthersCheckBox.Text = 'Switch Off Others';
            app.SwitchOffOthersCheckBox.Value = true;
            app.ApplyimmediatelyCheckBox = uicheckbox(app.DCDCConverterPanel);
            app.ApplyimmediatelyCheckBox.Text = 'Apply immediately';
            app.ApplyimmediatelyCheckBox.Value = true;
            app.RedEditFieldLabel = uilabel(app.DCDCConverterPanel);
            app.RedEditFieldLabel.Text = 'Red:';
            app.RedEditField = uieditfield(app.DCDCConverterPanel, 'numeric');
            app.RedEditField.Limits = [0 255];
            app.RedEditField.ValueDisplayFormat = '%.0f';
            app.RedEditField.Value = 32;
            app.BlueEditFieldLabel = uilabel(app.DCDCConverterPanel);
            app.BlueEditFieldLabel.Text = 'Blue:';
            app.BlueEditField = uieditfield(app.DCDCConverterPanel, 'numeric');
            app.BlueEditField.Limits = [0 255];
            app.BlueEditField.ValueDisplayFormat = '%.0f';
            app.BlueEditField.Value = 32;
            app.SetButton = uibutton(app.DCDCConverterPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.LightShowButton = uibutton(app.DCDCConverterPanel, 'push');
            app.LightShowButton.ButtonPushedFcn = createCallbackFcn(app, @LightShowButtonValueChanged, true);
            app.LightShowButton.Text = 'Light Show';
            app.SetATtinyStatus = uibutton(app.DCDCConverterPanel, 'push');
            app.SetATtinyStatus.ButtonPushedFcn = createCallbackFcn(app, @SetATtinyStatusButtonPushed, true);
            app.SetATtinyStatus.Text = 'Set';
            app.GetATtinyStatus = uibutton(app.DCDCConverterPanel, 'push');
            app.GetATtinyStatus.ButtonPushedFcn = createCallbackFcn(app, @GetATtinyStatusButtonPushed, true);
            app.GetATtinyStatus.Text = 'Get';
            app.ATtinyStatusEditField_2Label = uilabel(app.DCDCConverterPanel);
            app.ATtinyStatusEditField_2Label.Text = 'ATtiny Status:';
            app.ATtinyStatusEditField = uieditfield(app.DCDCConverterPanel, 'text');
            app.ATtinyStatusEditField.Value = 'Unknown';
            app.GetStatus = uibutton(app.DCDCConverterPanel, 'push');
            app.GetStatus.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatus.Text = 'Get STATUS';
            app.Frequency2Spinner = uispinner(app.DCDCConverterPanel);
            app.Frequency2Spinner.Step = 0.1;
            app.Frequency2Spinner.Limits = [0.5 5];
            app.Frequency2Spinner.ValueDisplayFormat = '%.1f';
            app.Frequency2Spinner.ValueChangedFcn = createCallbackFcn(app, @Frequency2SpinnerValueChanged, true);
            app.Frequency2Spinner.Tooltip = {'Sets Frequency'};
            app.Frequency2Spinner.Value = 1;
            app.Frequency2SpinnerLabel = uilabel(app.DCDCConverterPanel);
            app.Frequency2SpinnerLabel.Text = 'Frequency2:';
            app.DutyCycle2Spinner = uispinner(app.DCDCConverterPanel);
            app.DutyCycle2Spinner.Step = 10;
            app.DutyCycle2Spinner.Limits = [10 90];
            app.DutyCycle2Spinner.ValueDisplayFormat = '%.0f';
            app.DutyCycle2Spinner.ValueChangedFcn = createCallbackFcn(app, @DutyCycle2SpinnerValueChanged, true);
            app.DutyCycle2Spinner.Tooltip = {'Sets Duty Cycle'};
            app.DutyCycle2Spinner.Value = 50;
            app.DutyCycle2SpinnerLabel = uilabel(app.DCDCConverterPanel);
            app.DutyCycle2SpinnerLabel.Text = 'Duty Cycle2:';
            app.MainBoardPanel = uipanel(app.BurnInBoardMaintenanceUIFigure);
            app.MainBoardPanel.Title = 'Main Board';
            app.MeasurementButton = uibutton(app.MainBoardPanel, 'push');
            app.MeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurementButtonPushed, true);
            app.MeasurementButton.Text = 'Measurement...';
            app.OhmLabel_2 = uilabel(app.MainBoardPanel);
            app.OhmLabel_2.Text = 'Ohm';
            app.VLabel_2 = uilabel(app.MainBoardPanel);
            app.VLabel_2.Text = 'V';
            app.OhmLabel = uilabel(app.MainBoardPanel);
            app.OhmLabel.Text = 'Ohm';
            app.VLabel = uilabel(app.MainBoardPanel);
            app.VLabel.Text = 'V';
            app.SingleMeasurementButton = uibutton(app.MainBoardPanel, 'push');
            app.SingleMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @SingleMeasurementButtonPressed, true);
            app.SingleMeasurementButton.Text = 'Single Measurement';
            app.StatusLEDCheckBox = uicheckbox(app.MainBoardPanel);
            app.StatusLEDCheckBox.ValueChangedFcn = createCallbackFcn(app, @statusLEDCheckBoxChanged, true);
            app.StatusLEDCheckBox.Text = 'Status LED';
            app.MuxDropDown = uidropdown(app.MainBoardPanel);
            app.MuxDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MuxDropDownOpening, true);
            app.MuxDropDown.ValueChangedFcn = createCallbackFcn(app, @MuxChannelChanged, true);
            app.MuxDropDownLabel = uilabel(app.MainBoardPanel);
            app.MuxDropDownLabel.Text = 'Mux:';
            app.DMMVEditField = uieditfield(app.MainBoardPanel, 'numeric');
            app.DMMVEditField.Editable = 'off';
            app.DMMVEditFieldLabel = uilabel(app.MainBoardPanel);
            app.DMMVEditFieldLabel.Text = 'DMM V:';
            app.DMMREditField = uieditfield(app.MainBoardPanel, 'numeric');
            app.DMMREditField.Editable = 'off';
            app.DMMREditFieldLabel = uilabel(app.MainBoardPanel);
            app.DMMREditFieldLabel.Text = 'DMM R:';
            app.ADCVEditField = uieditfield(app.MainBoardPanel, 'numeric');
            app.ADCVEditField.Editable = 'off';
            app.ADCVEditFieldLabel = uilabel(app.MainBoardPanel);
            app.ADCVEditFieldLabel.Text = 'ADC V:';
            app.ADCREditField = uieditfield(app.MainBoardPanel, 'numeric');
            app.ADCREditField.Editable = 'off';
            app.ADCREditFieldLabel = uilabel(app.MainBoardPanel);
            app.ADCREditFieldLabel.Text = 'ADC R:';
            app.GetTemperaturesButton = uibutton(app.MainBoardPanel, 'push');
            app.GetTemperaturesButton.ButtonPushedFcn = createCallbackFcn(app, @GetTemperaturesButtonPushed, true);
            app.GetTemperaturesButton.Text = 'Get Temperatures';
            app.T1Label = uilabel(app.MainBoardPanel);
            app.T1Label.Text = 'T1:';
            app.T1EditField = uieditfield(app.MainBoardPanel, 'numeric');
            app.T1EditField.Editable = 'off';
            app.T2EditField = uieditfield(app.MainBoardPanel, 'numeric');
            app.T2EditField.Editable = 'off';
            app.T2Label = uilabel(app.MainBoardPanel);
            app.T2Label.Text = 'T2:';
            app.T3EditField = uieditfield(app.MainBoardPanel, 'numeric');
            app.T3EditField.Editable = 'off';
            app.T2Label_2 = uilabel(app.MainBoardPanel);
            app.T2Label_2.Text = 'T3:';
            app.CLabel = uilabel(app.MainBoardPanel);
            app.CLabel.Text = '°C';
            app.minLabel_2 = uilabel(app.MainBoardPanel);
            app.minLabel_2.Text = 'min';
            app.everyEditFieldLabel = uilabel(app.MainBoardPanel);
            app.everyEditFieldLabel.Text = 'every';
            app.MeasureEvery = uieditfield(app.MainBoardPanel, 'numeric');
            app.MeasureEvery.Limits = [5 65536];
            app.MeasureEvery.ValueDisplayFormat = '%.0f';
            app.MeasureEvery.Value = 10;
            app.MeasureButton = uibutton(app.MainBoardPanel, 'state');
            app.MeasureButton.ValueChangedFcn = createCallbackFcn(app, @MeasureButtonValueChanged, true);
            app.MeasureButton.Text = 'Measure...';
            app.TimerMeasurementStatus = uilabel(app.MainBoardPanel);
            app.TimerMeasurementStatus.Text = '';
            app.FilenameLabel = uilabel(app.MainBoardPanel);
            app.FilenameLabel.Text = 'Filename:';
            app.Postfix = uieditfield(app.MainBoardPanel, 'text');
            app.FolderEditFieldLabel = uilabel(app.MainBoardPanel);
            app.FolderEditFieldLabel.Text = 'Folder:';
            app.Folder = uieditfield(app.MainBoardPanel, 'text');
            app.Folder.Editable = 'off';
            app.SetFolder = uibutton(app.MainBoardPanel, 'push');
            app.SetFolder.ButtonPushedFcn = createCallbackFcn(app, @SetFolderButtonPushed, true);
            app.SetFolder.Text = 'Set...';
            app.SkipEvery2ndCheckBox = uicheckbox(app.MainBoardPanel);
            app.SkipEvery2ndCheckBox.Text = 'Skip every 2nd Channel';
            app.InteractiveMeasurement = uicheckbox(app.MainBoardPanel);
            app.InteractiveMeasurement.Text = 'Interactive';
            app.SkipTimer = uibutton(app.MainBoardPanel, 'push');
            app.SkipTimer.ButtonPushedFcn = createCallbackFcn(app, @SkipTimerButtonPushed, true);
            app.SkipTimer.Text = 'Skip wait';
            app.ConnectPanel = uipanel(app.BurnInBoardMaintenanceUIFigure);
            app.ConnectPanel.Title = 'Connect';
            app.SetUUID_Main = uibutton(app.ConnectPanel, 'push');
            app.SetUUID_Main.ButtonPushedFcn = createCallbackFcn(app, @SetUUID_MainButtonPushed, true);
            app.SetUUID_Main.Text = 'Set';
            app.GetUUID_Main = uibutton(app.ConnectPanel, 'push');
            app.GetUUID_Main.ButtonPushedFcn = createCallbackFcn(app, @GetUUID_MainButtonPushed, true);
            app.GetUUID_Main.Text = 'Get';
            app.SetUUID_DCDC = uibutton(app.ConnectPanel, 'push');
            app.SetUUID_DCDC.ButtonPushedFcn = createCallbackFcn(app, @SetUUID_DCDCButtonPushed, true);
            app.SetUUID_DCDC.Text = 'Set';
            app.GetUUID_DCDC = uibutton(app.ConnectPanel, 'push');
            app.GetUUID_DCDC.ButtonPushedFcn = createCallbackFcn(app, @GetUUID_DCDCButtonPushed, true);
            app.GetUUID_DCDC.Text = 'Get';
            app.MainBoardStatus = uilamp(app.ConnectPanel);
            app.MainBoardStatus.Tooltip = {'Status of Main Board'};
            app.MainBoardLabel = uilabel(app.ConnectPanel);
            app.MainBoardLabel.Text = 'Main Board:';
            app.DCDCLabel = uilabel(app.ConnectPanel);
            app.DCDCLabel.Text = 'DC/DC:';
            app.DCDCStatus = uilamp(app.ConnectPanel);
            app.DCDCStatus.Tooltip = {'Status of DC/DC Converter'};
            app.MBCStatus = uilamp(app.ConnectPanel);
            app.MBCStatus.Tooltip = {'Status of MBC'};
            app.DMMStatus = uilamp(app.ConnectPanel);
            app.DMMStatus.Tooltip = {'Status of DMM'};
            app.ConnectBoardsButton = uibutton(app.ConnectPanel, 'push');
            app.ConnectBoardsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectBoardsButtonPushed, true);
            app.ConnectBoardsButton.Text = 'Connect Boards';
            app.ConnectMBCButton = uibutton(app.ConnectPanel, 'push');
            app.ConnectMBCButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectMBCButtonPushed, true);
            app.ConnectMBCButton.Text = 'Connect MBC';
            app.ConnectDMMButton = uibutton(app.ConnectPanel, 'push');
            app.ConnectDMMButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDMMButtonPushed, true);
            app.ConnectDMMButton.Tooltip = {'Press to reconnect to DMM6500'};
            app.ConnectDMMButton.Text = 'Connect DMM';
            app.I2COffsetDropDown = uidropdown(app.ConnectPanel);
            app.I2COffsetDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.I2COffsetDropDown.Value = '0';
            app.I2COffsetDropDownLabel = uilabel(app.ConnectPanel);
            app.I2COffsetDropDownLabel.Text = 'I2C Offset:';
            app.DMM6500IDEditField = uieditfield(app.ConnectPanel, 'text');
            app.DMM6500IDEditField.Tooltip = {'On DMM: Menu, column System, Communication, USB: Lower right corner'};
            app.DMM6500IDEditFieldLabel = uilabel(app.ConnectPanel);
            app.DMM6500IDEditFieldLabel.Text = {'DMM6500 ID:'; ''};
            app.UUID_Main = uieditfield(app.ConnectPanel, 'text');
            app.UUID_Main.CharacterLimits = [0 36];
            app.UUID_Main.Tooltip = {'Enter 16 Hex Values here.'};
            app.UUIDLabel_3 = uilabel(app.ConnectPanel);
            app.UUIDLabel_3.Text = 'UUID:';
            app.DCDCSWVersion = uieditfield(app.ConnectPanel, 'text');
            app.DCDCSWVersion.CharacterLimits = [0 10];
            app.DCDCSWVersion.Editable = 'off';
            app.DCDCSWVersion.Enable = 'off';
            app.DCDCSWVersion.Tooltip = {'Software version flashed to ATtiny on DCDC Converter'};
            app.UUIDLabel = uilabel(app.ConnectPanel);
            app.UUIDLabel.Text = 'Version:';
            app.UUID_DCDC = uieditfield(app.ConnectPanel, 'text');
            app.UUID_DCDC.CharacterLimits = [0 36];
            app.UUID_DCDC.Tooltip = {'Enter 16 Hex Values here.'};
            app.UUIDLabel_2 = uilabel(app.ConnectPanel);
            app.UUIDLabel_2.Text = 'UUID:';
            app.MBCEditField = uieditfield(app.ConnectPanel, 'text');
            app.MBCEditField.Tooltip = {'Enter COM Port or IP Address of MBC'};
            app.DMM6500IDLabel = uilabel(app.ConnectPanel);
            app.DMM6500IDLabel.Text = 'MBC:';
            app.TestButton = uibutton(app.ConnectPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Enable = 'off';
            app.TestButton.Visible = 'off';
            app.TestButton.Text = 'Test...';
            app.CommunicationSettings = uibutton(app.ConnectPanel, 'push');
            app.CommunicationSettings.ButtonPushedFcn = createCallbackFcn(app, @CommunicationSettingsButtonPushed, true);
            app.CommunicationSettings.Text = 'Communication Settings...';
            app.LabelStatus = uilabel(app.BurnInBoardMaintenanceUIFigure);
            app.LabelStatus.Text = '';
            % Show the figure after all components are created
            app.BurnInBoardMaintenanceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurnInBoardMaintenanceApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BurnInBoardMaintenanceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @Initialize)
            else

                % Focus the running singleton app
                figure(runningApp.BurnInBoardMaintenanceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BurnInBoardMaintenanceUIFigure)
        end
    end
end