classdef BurnInBoardMaintenanceMeasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BurnInBoardMaintenanceUIFigure  matlab.ui.Figure
        SkipEvery2ndCheckBox            matlab.ui.control.CheckBox
        CloseButton                     matlab.ui.control.Button
        StepwiseMeasurementButton       matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ResetMeasurementResultsButton   matlab.ui.control.Button
        CompleteMeasurementButton       matlab.ui.control.Button
        Resistance                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        appName = "Measurement";
        version = "1.1.0";
        main
        RM = zeros(12, 10, 'double') + inf;
        VDC = zeros(1, 120, 'double');
        VR = zeros(1, 120, 'double');
        R = zeros(1, 120, 'double') + inf;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app, burnInBoardMaintenanceApp)
            app.main = burnInBoardMaintenanceApp;

            app.BurnInBoardMaintenanceUIFigure.Name = convertStringsToChars(sprintf("%s (Version: %s)", app.appName, app.version));
            
            chr(1) = "";
            for k=1:12
                chr(k)=int2str(k);
            end
            app.Resistance.ColumnName = chr(1:10);
            app.Resistance.RowName = chr(1:12);
            app.Resistance.Data = app.RM;
            app.SkipEvery2ndCheckBox.Value = app.main.SkipEvery2ndCheckBox.Value;
        end

        % Close request function: BurnInBoardMaintenanceUIFigure
        function BurnInBoardMaintenanceUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: CompleteMeasurementButton
        function CompleteMeasurementButtonPressed(app, event)
            %app.completeMeasurement();
            if app.SkipEvery2ndCheckBox.Value
                nSkip = 2;
            else
                nSkip = 0;
            end
            [app.VDC, app.VR, app.R, app.RM] = app.main.burnInDevice.completeMeasurement([nSkip, nSkip], 5, 120, app.main.dmm, app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPressed(app, event)
            [file,path] = uiputfile('*.csv');
            if ~(isequal(file,0) || isequal(path,0))
               app.main.burnInDevice.saveMeasurement(fullfile(path,file), datetime('now', 'Format', 'yyy-MM-dd'), datetime('now', 'Format', 'HH:mm:ss'), "", "", app.VDC, app.VR, app.R);
            end
        end

        % Button pushed function: ResetMeasurementResultsButton
        function ResetMeasurementResultsButtonPushed(app, event)
            app.RM = zeros(12, 10, 'double') + inf;
            app.Resistance.Data = app.RM;
        end

        % Button pushed function: StepwiseMeasurementButton
        function StepwiseMeasurementButtonPushed(app, event)
            debug = app.main.burnInDevice.mainBoard.debug;
            app.main.burnInDevice.mainBoard.debug = 0;
            progress = uiprogressdlg( app.BurnInBoardMaintenanceUIFigure, 'Title', 'Connect to channel:', 'Value', 0, "Cancelable","on","CancelText",'Cancel');
            app.RM = zeros(12, 10, 'double') + inf;
            app.R = zeros(1, 120, 'double') + inf;
            app.Resistance.Data = app.RM;
            nTries = 100;
            i = 0;
            app.main.burnInDevice.DCDCConv.switchVDC(1,1);
            app.main.burnInDevice.DCDCConv.switchVDC(2,1);
            %select measurement device
            dmm = app.main.dmm;
            if ~isvalid(dmm)
                dmm = Keithley6500.empty; %use ADC
            else
                try
                    %does the DMM really work?
                    VDC = dmm.meas_VDC();
                catch e
                    dmm = Keithley6500.empty; %use ADC
                end
            end
            %Upper half
            uiwait(msgbox('Starting with VDC1.\n\nMake sure the UPPER test resistor is used!'));
            for c = 1:10
                for r = 1:6
                    app.main.burnInDevice.mainBoard.switchRGBLED(i+1, 204, 0, 0, true, true);
                    progress.Message = sprintf('Channel: %d %d (%d/120)', r, c, i);
                    if progress.CancelRequested
                        break;
                    end
                    for k = 1:nTries
                        Rm = app.main.burnInDevice.meas_R(i,dmm);
                        fprintf('Rinitial = %f Ohm\n', Rm);
                        if Rm > 0.0 && Rm < 1000
                            pause(0.2);
                            Rv = [0.0, 0.0, 0.0, 0.0, 0.0];
                            for n = 1:10
                                for m = 1:5
                                    Rv(m) = app.main.burnInDevice.meas_R(i,dmm);
                                    sound(sin(0.5:10));
                                end
                                fprintf('Rmean(%d, %d) = %f\tRdelta = %f\n', r, c, mean(Rv), max(Rv) - min(Rv));
                                if (max(Rv) - min(Rv)) < 0.1 * mean(Rv)
                                    break;
                                end
                                if progress.CancelRequested
                                    break;
                                end
                            end
                            app.RM(r, c) = mean(Rv);
                            app.R(i+1) = app.RM(r, c);
                            app.Resistance.Data = app.RM;
                            sound(sin(0.5:1000));
                            break;
                        else
                            sound(sin(0.5:100));
                            pause(0.2);
                        end
                        if progress.CancelRequested
                            break
                        end
                    end
                    progress.Value = i/120;
                    progress.Message = sprintf('%d /120', i);
                    i = i + 1;
                end
                if progress.CancelRequested
                    break
                end
            end
            %Lower half
            if ~progress.CancelRequested
                uiwait(msgbox('Continuing with VDC2.\n\nMake sure the LOWER test resistor is used!'));
                for c = 1:10
                    for r = 7:12
                        app.main.burnInDevice.mainBoard.switchRGBLED(i+1, 204, 0, 0, true, true);
                        progress.Message = sprintf('Channel: %d %d (%d/120)', r, c, i);
                        if progress.CancelRequested
                            break;
                        end
                        for k = 1:nTries
                            Rm = app.main.burnInDevice.meas_R(i,dmm);
                            fprintf('Rinitial = %f Ohm\n', Rm);
                            if Rm > 0.0 && Rm < 1000
                                pause(0.2);
                                Rv = [0.0, 0.0, 0.0, 0.0, 0.0];
                                for n = 1:10
                                    for m = 1:5
                                        Rv(m) = app.main.burnInDevice.meas_R(i,dmm);
                                        sound(sin(0.5:10));
                                    end
                                    fprintf('Rmean(%d, %d) = %f\tRdelta = %f\n', r, c, mean(Rv), max(Rv) - min(Rv));
                                    if (max(Rv) - min(Rv)) < 0.01 * mean(Rv)
                                        break;
                                    end
                                    if progress.CancelRequested
                                        break;
                                    end
                                end
                                app.RM(r, c) = mean(Rv);
                                app.R(i+1) = app.RM(r, c);
                                app.Resistance.Data = app.RM;
                                sound(sin(0.5:1000));
                                break;
                            else
                                sound(sin(0.5:100));
                                pause(0.2);
                            end
                            if progress.CancelRequested
                                break
                            end
                        end
                        progress.Value = i/120;
                        progress.Message = sprintf('%d /120', i);
                        i = i + 1;
                    end
                    if progress.CancelRequested
                        break
                    end
                end
            end
            app.main.burnInDevice.mainBoard.switchRGBLED(0, 0, 0, 0, true, true);
            app.main.burnInDevice.mainBoard.setMUX(-1);
            app.main.burnInDevice.DCDCConv.switchVDC(1,app.main.getSwitchVDC(1));
            app.main.burnInDevice.DCDCConv.switchVDC(2,app.main.getSwitchVDC(2));
            app.main.burnInDevice.mainBoard.debug = debug;
            %Update table
            app.Resistance.Data = app.RM;            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BurnInBoardMaintenanceUIFigure = uifigure('Visible', 'off');
            app.BurnInBoardMaintenanceUIFigure.Name = 'BurnIn Board Maintenance';
            app.BurnInBoardMaintenanceUIFigure.CloseRequestFcn = createCallbackFcn(app, @BurnInBoardMaintenanceUIFigureCloseRequest, true);
            app.Resistance = uitable(app.BurnInBoardMaintenanceUIFigure);
            app.Resistance.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Resistance.RowName = {};
            app.CompleteMeasurementButton = uibutton(app.BurnInBoardMaintenanceUIFigure, 'push');
            app.CompleteMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @CompleteMeasurementButtonPressed, true);
            app.CompleteMeasurementButton.Text = 'Complete Measurement';
            app.ResetMeasurementResultsButton = uibutton(app.BurnInBoardMaintenanceUIFigure, 'push');
            app.ResetMeasurementResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMeasurementResultsButtonPushed, true);
            app.ResetMeasurementResultsButton.Text = 'Reset Measurement Results';
            app.SaveButton = uibutton(app.BurnInBoardMaintenanceUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPressed, true);
            app.SaveButton.Text = 'Save...';
            app.StepwiseMeasurementButton = uibutton(app.BurnInBoardMaintenanceUIFigure, 'push');
            app.StepwiseMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StepwiseMeasurementButtonPushed, true);
            app.StepwiseMeasurementButton.Text = 'Stepwise Measurement';
            app.CloseButton = uibutton(app.BurnInBoardMaintenanceUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SkipEvery2ndCheckBox = uicheckbox(app.BurnInBoardMaintenanceUIFigure);
            app.SkipEvery2ndCheckBox.Text = 'Skip every 2nd';
            % Show the figure after all components are created
            app.BurnInBoardMaintenanceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurnInBoardMaintenanceMeasApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BurnInBoardMaintenanceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)Initialize(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BurnInBoardMaintenanceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BurnInBoardMaintenanceUIFigure)
        end
    end
end