classdef BurnInProfileViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Bank2Panel                 matlab.ui.container.Panel
        OhmLabel2                  matlab.ui.control.Label
        Number2                    matlab.ui.control.NumericEditField
        NumberLabel_2              matlab.ui.control.Label
        Resistance2                matlab.ui.control.NumericEditField
        Resistance1Label_2         matlab.ui.control.Label
        Type2                      matlab.ui.control.EditField
        TypeLabel_2                matlab.ui.control.Label
        OhmLabel22                 matlab.ui.control.Label
        Deviation2                 matlab.ui.control.NumericEditField
        DeviationEditFieldLabel_2  matlab.ui.control.Label
        MeasurementRate            matlab.ui.control.EditField
        Measuremen1RateLabel       matlab.ui.control.Label
        OhmLabel1                  matlab.ui.control.Label
        Resistance1                matlab.ui.control.NumericEditField
        Resistance1Label           matlab.ui.control.Label
        Number1                    matlab.ui.control.NumericEditField
        NumberLabel                matlab.ui.control.Label
        Type1                      matlab.ui.control.EditField
        TypeLabel                  matlab.ui.control.Label
        FileName                   matlab.ui.control.EditField
        FileNameEditFieldLabel     matlab.ui.control.Label
        Profile                    matlab.ui.control.Table
        Bank1Panel                 matlab.ui.container.Panel
        OhmLabel                   matlab.ui.control.Label
        Deviation1                 matlab.ui.control.NumericEditField
        DeviationEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        currProfileStep = 0;
        profile BurnInProfile;
        nBanks;
    end
    
    methods (Access = public)
        
        function res = updateProfileTable(app)
            res = 1;
            if app.nBanks < 2
                app.Bank2Panel.Visible = "off";
                
            end
            chr(1) = "";
            for k=1:1:app.profile.getNoSteps()
                app.Profile.Data{k,1} = app.profile.p(k).Name;
                app.Profile.Data{k,2} = sprintf('%s', app.profile.p(k).Duration);
                app.Profile.Data{k,3} = sprintf('%f', app.profile.p(k).PowerPerChannel(1));
                app.Profile.Data{k,4} = sprintf('%f', app.profile.p(k).MaxCurrent(1));
                app.Profile.Data{k,5} = sprintf('%f', app.profile.p(k).MaxVoltage(1));
                app.Profile.Data{k,6} = sprintf('%f', app.profile.p(k).Frequency(1));
                app.Profile.Data{k,7} = sprintf('%d', app.profile.p(k).DutyCycle(1));
                app.Profile.Data{k,8} = sprintf('%d', app.profile.p(k).ComputeVDuT(1));
                if app.nBanks > 1
                    app.Profile.Data{k,9} = sprintf('%f', app.profile.p(k).PowerPerChannel(2));
                    app.Profile.Data{k,10} = sprintf('%f', app.profile.p(k).MaxCurrent(2));
                    app.Profile.Data{k,11} = sprintf('%f', app.profile.p(k).MaxVoltage(2));
                    app.Profile.Data{k,12} = sprintf('%f', app.profile.p(k).Frequency(2));
                    app.Profile.Data{k,13} = sprintf('%d', app.profile.p(k).DutyCycle(2));
                    app.Profile.Data{k,14} = sprintf('%d', app.profile.p(k).ComputeVDuT(2));
                else
                    for j=9:1:14
                        app.Profile.Data{k,j} = sprintf('n/a');
                    end
                end
                app.Profile.Data{k,15} = sprintf('%d', app.profile.p(k).Measure);
                chr(k)=int2str(k);
            end
            app.Profile.RowName = chr(1:app.profile.getNoSteps());
            app.FileName.Value = app.profile.completeFileName;
            app.Type1.Value = app.profile.Type(1);
            app.Number1.Value = app.profile.NumberOfChannels(1);
            app.Resistance1.Value = app.profile.Resistance(1);
            app.Deviation1.Value = app.profile.Deviation(1);
            app.Type2.Value = app.profile.Type(2);
            app.Number2.Value = app.profile.NumberOfChannels(2);
            app.Resistance2.Value = app.profile.Resistance(2);
            app.Deviation2.Value = app.profile.Deviation(2);
            app.MeasurementRate.Value = sprintf("%s", app.profile.MeasurementRate);

            %Mark currently active Profile step
            if app.profile.getStatus() == ProfileStatusEnum.RUNNING
                for k=1:1:size(app.Profile.Data, 2)
                    if app.profile.currProfileStep > 0
                        currStep = app.profile.currProfileStep;
                    else
                        currStep = 1;
                    end
                    addStyle(app.Profile,uistyle('BackgroundColor', [1.0 0.8 0.0]), 'cell',[currStep,k]);
                end
            end
            %TODO: Show Sum of durations
            drawnow();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app, profile, nBanks)
            app.UIFigure.Name = 'BurnIn Profile Viewer';
            app.profile = profile;
            app.nBanks = nBanks;
            app.updateProfileTable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Bank1Panel = uipanel(app.UIFigure);
            app.Bank1Panel.Title = 'Bank 1';
            app.DeviationEditFieldLabel = uilabel(app.Bank1Panel);
            app.DeviationEditFieldLabel.Text = 'Deviation:';
            app.Deviation1 = uieditfield(app.Bank1Panel, 'numeric');
            app.Deviation1.Editable = 'off';
            app.OhmLabel = uilabel(app.Bank1Panel);
            app.OhmLabel.Text = 'Ohm';
            app.Profile = uitable(app.UIFigure);
            app.Profile.ColumnName = {'Name'; 'Duration'; 'p1'; 'Imax1'; 'Vmax1'; 'f1'; 'duty1'; 'ComputeVDuT1'; 'p2'; 'Imax2'; 'Vmax2'; 'f2'; 'duty2'; 'ComputeVDuT2'; 'Measure'};
            app.Profile.RowName = {};
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileName = uieditfield(app.UIFigure, 'text');
            app.FileName.Editable = 'off';
            app.TypeLabel = uilabel(app.UIFigure);
            app.TypeLabel.Text = 'Type:';
            app.Type1 = uieditfield(app.UIFigure, 'text');
            app.Type1.Editable = 'off';
            app.NumberLabel = uilabel(app.UIFigure);
            app.NumberLabel.Text = 'Number:';
            app.Number1 = uieditfield(app.UIFigure, 'numeric');
            app.Number1.Editable = 'off';
            app.Resistance1Label = uilabel(app.UIFigure);
            app.Resistance1Label.Text = 'Resistance:';
            app.Resistance1 = uieditfield(app.UIFigure, 'numeric');
            app.Resistance1.Editable = 'off';
            app.OhmLabel1 = uilabel(app.UIFigure);
            app.OhmLabel1.Text = 'Ohm';
            app.Measuremen1RateLabel = uilabel(app.UIFigure);
            app.Measuremen1RateLabel.Text = 'Measurement Rate:';
            app.MeasurementRate = uieditfield(app.UIFigure, 'text');
            app.Bank2Panel = uipanel(app.UIFigure);
            app.Bank2Panel.Title = 'Bank 2';
            app.DeviationEditFieldLabel_2 = uilabel(app.Bank2Panel);
            app.DeviationEditFieldLabel_2.Text = 'Deviation:';
            app.Deviation2 = uieditfield(app.Bank2Panel, 'numeric');
            app.Deviation2.Editable = 'off';
            app.OhmLabel22 = uilabel(app.Bank2Panel);
            app.OhmLabel22.Text = 'Ohm';
            app.TypeLabel_2 = uilabel(app.Bank2Panel);
            app.TypeLabel_2.Text = 'Type:';
            app.Type2 = uieditfield(app.Bank2Panel, 'text');
            app.Type2.Editable = 'off';
            app.Resistance1Label_2 = uilabel(app.Bank2Panel);
            app.Resistance1Label_2.Text = 'Resistance:';
            app.Resistance2 = uieditfield(app.Bank2Panel, 'numeric');
            app.Resistance2.Editable = 'off';
            app.NumberLabel_2 = uilabel(app.Bank2Panel);
            app.NumberLabel_2.Text = 'Number:';
            app.Number2 = uieditfield(app.Bank2Panel, 'numeric');
            app.Number2.Editable = 'off';
            app.OhmLabel2 = uilabel(app.Bank2Panel);
            app.OhmLabel2.Text = 'Ohm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurnInProfileViewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)Initialize(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end