classdef CommunicationSettingsDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CommunicationSettingsUIFigure  matlab.ui.Figure
        errMsg                         matlab.ui.control.Label
        APPW                           matlab.ui.control.EditField
        UUIDLabel_8                    matlab.ui.control.Label
        WiFiPW                         matlab.ui.control.EditField
        UUIDLabel_7                    matlab.ui.control.Label
        WiFiSSID                       matlab.ui.control.EditField
        UUIDLabel_6                    matlab.ui.control.Label
        WiFiName                       matlab.ui.control.EditField
        UUIDLabel_5                    matlab.ui.control.Label
        EthGateway                     matlab.ui.control.EditField
        UUIDLabel_4                    matlab.ui.control.Label
        EthSubnet                      matlab.ui.control.EditField
        UUIDLabel_3                    matlab.ui.control.Label
        EthIP                          matlab.ui.control.EditField
        UUIDLabel_2                    matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        ModeLabel                      matlab.ui.control.Label
        UUIDLabel                      matlab.ui.control.Label
        EthMAC                         matlab.ui.control.EditField
        SetButton                      matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        GetButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        main
        settings CommunicationSettings;
        editFields
    end
    
    methods (Access = private)
        function tf = isValidMacAddress(app, str)
            tf = ischar(str) && isrow(str) && ...
            ~isempty(regexp(str, ...
                '^[0-9A-Fa-f]{2}(:[0-9A-Fa-f]{2}){5}$', ...
                'once'));
        end

        function macHex = decimalMacToHex(app, macDec)
        % decimalMacToHex Convert dot-separated decimal MAC to hex MAC
        %
        %   macHex = decimalMacToHex("6.0.0.174.153.72")
        %   returns "06:00:00:AE:99:48"
        
            % Split string at dots
            parts = split(macDec, ".");
        
            % Validate length
            if numel(parts) ~= 6
                error("MAC address must contain exactly 6 elements.");
            end
        
            % Convert to numbers
            decVals = str2double(parts);
        
            % Validate numeric range
            if any(isnan(decVals)) || any(decVals < 0 | decVals > 255)
                error("Each MAC element must be an integer between 0 and FF.");
            end
        
            % Convert to 2-digit hexadecimal strings
            hexParts = string(upper(dec2hex(decVals, 2)));
        
            % Join with colons
            macHex = strjoin(hexParts, ":");
        end
    
        function macDec = hexMacToDecimal(app, macHex)
            % hexMacToDecimal Convert colon-separated hex MAC to decimal MAC
            %
            %   macDec = hexMacToDecimal(app, "06:00:00:AE:99:48")
            %   returns "6.0.0.174.153.72"
        
            % Split string at colons
            parts = split(macHex, ":");
        
            % Validate length
            if numel(parts) ~= 6
                error("MAC address must contain exactly 6 elements.");
            end
        
            % Convert hex strings to decimal values
            decVals = hex2dec(parts);
        
            % Validate conversion
            if any(isnan(decVals))
                error("Each MAC element must be a valid hexadecimal value.");
            end
        
            % Convert to strings and join with dots
            decParts = string(decVals);
            macDec = strjoin(decParts, ".");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app, burnInApp)
            app.main = burnInApp;
            app.settings = CommunicationSettings("BurnInMaintenanceCommSettings");
            
            app.editFields = [app.EthMAC app.EthIP app.EthSubnet app.EthGateway app.WiFiName app.WiFiSSID app.WiFiPW app.APPW];

            app.ModeDropDown.Value = app.settings.opt.transportMode;
            app.EthMAC.Value = app.settings.opt.ethMAC;
            app.EthIP.Value = app.settings.opt.ethIP;
            app.EthSubnet.Value = app.settings.opt.ethSubnet;
            app.EthGateway.Value = app.settings.opt.ethGateway;
            app.WiFiName.Value = app.settings.opt.wifiAPName;
            app.WiFiSSID.Value = app.settings.opt.wifiSSID;
            app.WiFiPW.Value = app.settings.opt.wifiPassword;
            app.APPW.Value = app.settings.opt.apPassWord;
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            app.errMsg.Text = "";
            color = [1.0 1.0 1.0];
            %reset color of all entries
            for k=1:1:size(app.editFields, 2)
                app.editFields(k).BackgroundColor = color;
            end
            try
                strOld = "";
                for k=1:1:size(app.editFields, 2)
                    strOld = app.editFields(k).Value;
                    app.editFields(k).Value = "Getting...";
                    drawnow();
                    app.editFields(k).BackgroundColor = [0.8 0 0];
                    command = sprintf("OR%d", k);
                    str = app.main.mbc.queryString(0, command);
                    %fprintf("str = %s\n", str);
                    if((strlength(string(str)) == 0) || str == "001" )
                        error("Make sure the Board is connected and reachable!");
                    end
                    %transform MAC address
                    if(k==1)
                        str = app.decimalMacToHex(str);
                    end
                    app.editFields(k).Value = str;
                    app.editFields(k).BackgroundColor = color;
                    drawnow();
                end
            catch e
                app.editFields(k).Value = strOld;
                app.errMsg.Text = sprintf("Error:\n%s\n%s", "Settings could not be retrieved!", e.message);
                return;
            end
            %Handle Transport Mode
            color = app.ModeDropDown.BackgroundColor;
            app.ModeDropDown.BackgroundColor = [0.8 0 0];
            str = app.main.mbc.queryString(0, "OR0");
            app.ModeDropDown.Value = str2double(str);
            app.ModeDropDown.BackgroundColor = color;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.settings.opt.transportMode = app.ModeDropDown.Value;
            app.settings.opt.ethMAC        = app.EthMAC.Value;
            app.settings.opt.ethIP         = app.EthIP.Value;
            app.settings.opt.ethSubnet     = app.EthSubnet.Value;
            app.settings.opt.ethGateway    = app.EthGateway.Value;
            app.settings.opt.wifiAPName    = app.WiFiName.Value;
            app.settings.opt.wifiSSID      = app.WiFiSSID.Value;
            app.settings.opt.wifiPassword  = app.WiFiPW.Value;
            app.settings.opt.apPassWord    = app.APPW.Value;
            delete(app);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            strError = "";
            color = [1.0 1.0 1.0];
            %reset color of all entries
            for k=1:1:size(app.editFields, 2)
                app.editFields(k).BackgroundColor = color;
            end
            %Check syntax of all entries
            for k=1:1:size(app.editFields, 2)
                if(k==1)
                    if ~app.isValidMacAddress(app.editFields(k).Value)
                        strError = "Not a valid MAC Address! Syntax: AA:BB:CC:DD";
                        break;
                    end
                elseif k > 1 && k < 5
                    if ~checkIPAddress(app.editFields(k).Value)
                        strError = "Not a valid IP address! Syntax: 123.456.789.001";
                        break;
                    end
                end
            end
            if strError ~= ""
                drawnow();
                app.editFields(k).BackgroundColor = [0.8 0 0];
                drawnow();
                app.editFields(k).focus;
                uialert(app.CommunicationSettingsUIFigure, strError,"Error");
                uiwait(app.CommunicationSettingsUIFigure);
                return;
            end
            try
                app.errMsg.Text = "";
                for k=1:1:size(app.editFields, 2)
                    app.editFields(k).BackgroundColor = [0.8 0 0];
                    drawnow();
                    str = app.editFields(k).Value;
                    %transform MAC address
                    if(k==1)
                        str = app.hexMacToDecimal(str);
                    end
                    command = sprintf("OW%d%s", k, str);
                    str = app.main.mbc.queryString(0, command);
                    %fprintf("str = %s\n", str);
                    if(str ~= "000")
                        error("Make sure the Board is connected and reachable!");
                    end
                    app.editFields(k).BackgroundColor = color;
                    drawnow();
                end
                %Handle Transport Mode
                color = app.ModeDropDown.BackgroundColor;
                app.ModeDropDown.BackgroundColor = [0.8 0 0];
                command = sprintf("OW0%d", app.ModeDropDown.Value);
                str = app.main.mbc.queryString(0, command);
                app.ModeDropDown.BackgroundColor = color;
    
                %store settings in NV RAM
                app.main.mbc.queryString(0, "OW9");
            catch e
                app.errMsg.Text = sprintf("Error: %s\n%s", str, e.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CommunicationSettingsUIFigure = uifigure('Visible', 'off');
            app.CommunicationSettingsUIFigure.Name = 'Communication Settings';
            app.GetButton = uibutton(app.CommunicationSettingsUIFigure, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.CloseButton = uibutton(app.CommunicationSettingsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SetButton = uibutton(app.CommunicationSettingsUIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.EthMAC = uieditfield(app.CommunicationSettingsUIFigure, 'text');
            app.EthMAC.CharacterLimits = [0 17];
            app.EthMAC.Tooltip = {'Enter 6 Hex Values here, separated by '':''!'};
            app.EthMAC.Value = '06:00:00:AE:99:48';
            app.UUIDLabel = uilabel(app.CommunicationSettingsUIFigure);
            app.UUIDLabel.Text = 'Eth MAC:';
            app.ModeLabel = uilabel(app.CommunicationSettingsUIFigure);
            app.ModeLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.CommunicationSettingsUIFigure);
            app.ModeDropDown.Items = {'Ethernet', 'WiFi or Accesspoint', 'Auto'};
            app.ModeDropDown.ItemsData = [0 1 2];
            app.ModeDropDown.Value = 0;
            app.UUIDLabel_2 = uilabel(app.CommunicationSettingsUIFigure);
            app.UUIDLabel_2.Text = 'Eth IP:';
            app.EthIP = uieditfield(app.CommunicationSettingsUIFigure, 'text');
            app.EthIP.CharacterLimits = [0 19];
            app.EthIP.Tooltip = {'Enter an IP address here!'};
            app.EthIP.Value = '182.168.2.30';
            app.UUIDLabel_3 = uilabel(app.CommunicationSettingsUIFigure);
            app.UUIDLabel_3.Text = 'Eth Subnet:';
            app.EthSubnet = uieditfield(app.CommunicationSettingsUIFigure, 'text');
            app.EthSubnet.CharacterLimits = [0 19];
            app.EthSubnet.Tooltip = {'Enter an IP address here!'};
            app.EthSubnet.Value = '255.255.255.0';
            app.UUIDLabel_4 = uilabel(app.CommunicationSettingsUIFigure);
            app.UUIDLabel_4.Text = 'Eth Gateway:';
            app.EthGateway = uieditfield(app.CommunicationSettingsUIFigure, 'text');
            app.EthGateway.CharacterLimits = [0 19];
            app.EthGateway.Tooltip = {'Enter an IP address here!'};
            app.EthGateway.Value = '192.168.2.0';
            app.UUIDLabel_5 = uilabel(app.CommunicationSettingsUIFigure);
            app.UUIDLabel_5.Text = 'Wifi Name:';
            app.WiFiName = uieditfield(app.CommunicationSettingsUIFigure, 'text');
            app.WiFiName.CharacterLimits = [0 63];
            app.WiFiName.Tooltip = {'Enter a valid hostname here (64 characters, no special characters).'};
            app.WiFiName.Value = 'BurnInBoard1';
            app.UUIDLabel_6 = uilabel(app.CommunicationSettingsUIFigure);
            app.UUIDLabel_6.Text = 'WiFi SSID:';
            app.WiFiSSID = uieditfield(app.CommunicationSettingsUIFigure, 'text');
            app.WiFiSSID.CharacterLimits = [0 32];
            app.WiFiSSID.Tooltip = {'Enter max 32 characters here.'};
            app.WiFiSSID.Value = 'WLAN-206409';
            app.UUIDLabel_7 = uilabel(app.CommunicationSettingsUIFigure);
            app.UUIDLabel_7.Text = 'WiFi PW:';
            app.WiFiPW = uieditfield(app.CommunicationSettingsUIFigure, 'text');
            app.WiFiPW.CharacterLimits = [0 64];
            app.WiFiPW.Tooltip = {'Enter max. 64 characters here.'};
            app.WiFiPW.Value = '1234567890';
            app.UUIDLabel_8 = uilabel(app.CommunicationSettingsUIFigure);
            app.UUIDLabel_8.Text = 'AP PW:';
            app.APPW = uieditfield(app.CommunicationSettingsUIFigure, 'text');
            app.APPW.CharacterLimits = [0 20];
            app.APPW.Tooltip = {'Enter max. 64 characters here.'};
            app.APPW.Value = '12345678';
            app.errMsg = uilabel(app.CommunicationSettingsUIFigure);
            app.errMsg.Text = '';
            % Show the figure after all components are created
            app.CommunicationSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommunicationSettingsDlg(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CommunicationSettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)Initialize(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CommunicationSettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CommunicationSettingsUIFigure)
        end
    end
end