classdef SerialNumberDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label              matlab.ui.control.Label
        AA0001ZZ9999Label  matlab.ui.control.Label
        CancelButton       matlab.ui.control.Button
        OKButton           matlab.ui.control.Button
        Number             matlab.ui.control.NumericEditField
        LastPositionLabel  matlab.ui.control.Label
        SerialNumber       matlab.ui.control.EditField
        SerialNumberLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        main
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app, burnInApp)
            app.main = burnInApp;
            app.UIFigure.Name = 'Serial Number';
            app.SerialNumber.Value = app.main.burnInController.serialNumber(app.main.currBoard);
            app.Label.Text = sprintf("1 ... %d", app.main.burnInController.GetMaxNofEmitters(app.main.currBoard));
            app.Number.Limits = [1, app.main.burnInController.GetMaxNofEmitters(app.main.currBoard)];
            app.Number.Value = min(app.main.burnInController.numberOfEmitters(app.main.currBoard), app.main.burnInController.GetMaxNofEmitters(app.main.currBoard));
            focus(app.SerialNumber);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ~isempty(app.SerialNumber.Value)
                [b, SNr] = getSNr(app.SerialNumber.Value, 0);
                if b
                    app.main.burnInController.serialNumber(app.main.currBoard) = SNr;
                    app.main.burnInController.numberOfEmitters(app.main.currBoard) = app.Number.Value;
                    [~, SNrend] = getSNr(SNr, app.Number.Value);
                    app.main.Status.Data{app.main.currBoard,2} = sprintf('%s - %s', SNr, SNrend);
                    delete(app);
                else
                    uialert(app.UIFigure, sprintf("'%s* is not a valid Serialnumber.\n\nEnter a valid Serialnumber for example 'AA0001'", app.SerialNumber.Value), "Error in Serialnumber");
                end
            else
                app.main.burnInController.serialNumber(app.main.currBoard) = "";
                delete(app);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SerialNumberLabel = uilabel(app.UIFigure);
            app.SerialNumberLabel.Text = 'Serial Number:';
            app.SerialNumber = uieditfield(app.UIFigure, 'text');
            app.SerialNumber.CharacterLimits = [6 6];
            app.LastPositionLabel = uilabel(app.UIFigure);
            app.LastPositionLabel.Text = 'Last Position:';
            app.Number = uieditfield(app.UIFigure, 'numeric');
            app.Number.Limits = [1 120];
            app.Number.AllowEmpty = 'on';
            app.Number.Value = [];
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AA0001ZZ9999Label = uilabel(app.UIFigure);
            app.AA0001ZZ9999Label.Text = 'AA0001 ... ZZ9999';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1 ... 120';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialNumberDlg(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)Initialize(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end